classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Solution           matlab.ui.control.Label
        SolutionLabel      matlab.ui.control.Label
        SolvedImageLabel   matlab.ui.control.Label
        InputImageLabel    matlab.ui.control.Label
        SolvedImage        matlab.ui.control.Image
        SudokuSolverLabel  matlab.ui.control.Label
        OpenButton         matlab.ui.control.Button
        InputImage         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select Image to Open');
            input_img = imread([filepath, filename]);
            app.InputImage.ImageSource = input_img;

            [solution, solution_img] = sudolve(input_img);

            app.SolvedImage.ImageSource = solution_img;
            app.Solution.Text = cellstr(num2str(solution));
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputImage = uiimage(app.UIFigure);
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SudokuSolverLabel = uilabel(app.UIFigure);
            app.SudokuSolverLabel.Text = 'Sudoku Solver';
            app.SolvedImage = uiimage(app.UIFigure);
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Text = 'Input Image';
            app.SolvedImageLabel = uilabel(app.UIFigure);
            app.SolvedImageLabel.Text = 'Solved Image';
            app.SolutionLabel = uilabel(app.UIFigure);
            app.SolutionLabel.Text = 'Solution';
            app.Solution = uilabel(app.UIFigure);
            app.Solution.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end